classdef run_AVOCODO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        hand_editing               matlab.ui.Figure
        but_plot_EEG_raw           matlab.ui.control.Button
        but_plot_EEG_filtered      matlab.ui.control.Button
        but_remove_selected        matlab.ui.control.Button
        txt_video_time             matlab.ui.control.Label
        VideotimeLabel             matlab.ui.control.Label
        markerLabel                matlab.ui.control.Label
        txt_marker_type            matlab.ui.control.DropDown
        but_add_markers            matlab.ui.control.Button
        spin_speed                 matlab.ui.control.Spinner
        PlaybackspeedSpinnerLabel  matlab.ui.control.Label
        but_pause                  matlab.ui.control.Button
        but_play                   matlab.ui.control.Button
        txt_instruction_10         matlab.ui.control.Label
        txt_instruction_9          matlab.ui.control.Label
        txt_instruction_8          matlab.ui.control.Label
        txt_instruction_7          matlab.ui.control.Label
        txt_instruction_6          matlab.ui.control.Label
        txt_instruction_5          matlab.ui.control.Label
        txt_instruction_4          matlab.ui.control.Label
        txt_instruction_3          matlab.ui.control.Label
        txt_instruction_2          matlab.ui.control.Label
        txt_instruction_1          matlab.ui.control.Label
        switch_instructions        matlab.ui.control.Switch
        InstructionsSwitchLabel    matlab.ui.control.Label
        aLabel                     matlab.ui.control.Label
        but_forward_more           matlab.ui.control.Button
        but_forward                matlab.ui.control.Button
        but_back                   matlab.ui.control.Button
        but_back_more              matlab.ui.control.Button
        secondsLabel_3             matlab.ui.control.Label
        number_window_width        matlab.ui.control.NumericEditField
        WindowwidthLabel           matlab.ui.control.Label
        but_export_additional      matlab.ui.control.Button
        but_export                 matlab.ui.control.Button
        but_browse_script          matlab.ui.control.Button
        txt_script_remove_events   matlab.ui.control.EditField
        EditFieldLabel             matlab.ui.control.Label
        but_redo                   matlab.ui.control.Button
        but_undo                   matlab.ui.control.Button
        but_drop                   matlab.ui.control.Button
        TableofaddedmarkersLabel   matlab.ui.control.Label
        table_markers              matlab.ui.control.Table
        list_eeg_files             matlab.ui.control.ListBox
        FilesinpathListBoxLabel    matlab.ui.control.Label
        but_sync                   matlab.ui.control.Button
        number_slider              matlab.ui.control.NumericEditField
        secondsLabel_2             matlab.ui.control.Label
        slide_video                matlab.ui.control.Slider
        drop_number_videos         matlab.ui.control.DropDown
        ChoosevideoLabel           matlab.ui.control.Label
        lamp_load_video            matlab.ui.control.Lamp
        VideosloadedLabel          matlab.ui.control.Label
        lamp_load_eeg              matlab.ui.control.Lamp
        EEGloadedLampLabel         matlab.ui.control.Label
        but_load_eeg               matlab.ui.control.Button
        but_path_data              matlab.ui.control.Button
        txt_path_data              matlab.ui.control.EditField
        DatafolderLabel            matlab.ui.control.Label
        axis_spectrogram           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            func_gui_initializing(app);
        end

        % Button pushed function: but_path_data
        function but_path_dataButtonPushed(app, event)
            % Prompt to browse file path
            func_update_file_list(app,1)
        end

        % Button pushed function: but_load_eeg
        function but_load_eegButtonPushed(app, event)
            % Load EEG
            func_load_eeg(app);

            % Load videos and audios
            func_load_videos(app);
        end

        % Close request function: hand_editing
        function hand_editingCloseRequest(app, event)
            func_gui_closing(app);
        end

        % Callback function
        function but_load_videoButtonPushed(app, event)
            % Load videos and audios
            func_load_videos(app);
            
        end

        % Callback function
        function but_path_eeglabButtonPushed(app, event)
            % Prompt to browse path
            path_eeglab = uigetdir(app.txt_path_eeglab.Value);
            app.txt_path_eeglab.Value = path_eeglab;%update file path
            
            % add EEGLAB path
            addpath(genpath(path_eeglab),'-END');
        end

        % Callback function
        function but_video_playButtonPushed(app, event)
            % Play video
            func_play_video(app);
        end

        % Value changing function: slide_video
        function slide_videoValueChanging(app, event)
            changingValue = event.Value;
            func_time_change(app,changingValue);
        end

        % Value changed function: slide_video
        function slide_videoValueChanged(app, event)
            value = app.slide_video.Value;
            func_time_change(app,value);
        end

        % Value changed function: drop_number_videos
        function drop_number_videosValueChanged(app, event)
            value = app.drop_number_videos.Value;
            func_choose_video(app,value);
        end

        % Value changed function: number_slider
        function number_sliderValueChanged(app, event)
            value = app.number_slider.Value;
            func_time_change(app,value);
        end

        % Callback function
        function but_pauseValueChanged(app, event)
            value = app.but_pause.Value;
            if value%play if True
                % Play video
                app.but_pause.Text = 'Pause';
                func_play_video(app);
            else
                app.but_pause.Text = 'Play';
            end
        end

        % Button pushed function: but_sync
        function but_syncButtonPushed(app, event)
            vlc = getappdata(app.hand_editing,'vlc');
            time_vlc = vlc.Current.Position/1000000;%in seconds;
            time_gui = app.number_slider.Value;
            if abs(time_vlc-time_gui)>1%if video and gui time differs >1second
                func_time_change(app,time_vlc);
            end
        end

        % Button pushed function: but_drop
        function but_dropButtonPushed(app, event)
            % Drop event markers
            % Sync with video first
            vlc = getappdata(app.hand_editing,'vlc');
            if isempty(vlc.Current)%if video hasn't been played
                errordlg('No time has been selected. Play video first!!', 'Error');
                return;
            end

            time_vlc = vlc.Current.Position/1000000;%in seconds;
            time_gui = app.number_slider.Value;
            if abs(time_vlc-time_gui)>1%if video and gui time differs >1second
                func_time_change(app,time_vlc);
            end

            func_drop_marker(app);
        end

        % Button pushed function: but_undo
        function but_undoButtonPushed(app, event)
            % Remove last added marker
            func_undo_marker(app,0);
        end

        % Button pushed function: but_redo
        function but_redoButtonPushed(app, event)
            % Redo a marker deletion
            func_redo_marker(app);
        end

        % Button pushed function: but_export
        function but_exportButtonPushed(app, event)
            % Export EEG data
            func_export(app,0);%0 for no additional scripts
        end

        % Button pushed function: but_export_additional
        function but_export_additionalButtonPushed(app, event)
            func_export(app,1);%1 for additional scripts
        end

        % Value changed function: number_window_width
        function number_window_widthValueChanged(app, event)
            value = app.number_window_width.Value;
            time = app.number_slider.Value;
            % Update spectrogram
            window_width = value/2;%seconds
            xlim = [time-window_width,time+window_width];
            set(app.axis_spectrogram,'xlim',xlim);
        end

        % Button pushed function: but_back
        function but_backButtonPushed(app, event)
            time = app.number_slider.Value;
            time = max([time-0.1,0]);
            func_time_change(app,time);
        end

        % Button pushed function: but_back_more
        function but_back_moreButtonPushed(app, event)
            time = app.number_slider.Value;
            time = max([time-1,0]);
            func_time_change(app,time);
        end

        % Button pushed function: but_forward
        function but_forwardButtonPushed(app, event)
            time = app.number_slider.Value;
            time_max = app.slide_video.Limits(2);
            time = min([time+0.1,time_max]);
            func_time_change(app,time);
        end

        % Button pushed function: but_forward_more
        function but_forward_moreButtonPushed(app, event)
            time = app.number_slider.Value;
            time_max = app.slide_video.Limits(2);
            time = min([time+1,time_max]);
            func_time_change(app,time);
        end

        % Callback function
        function but_playValueChanged(app, event)
            func_play(app);
        end

        % Value changed function: switch_instructions
        function switch_instructionsValueChanged(app, event)
            value = app.switch_instructions.Value;
            func_show_instructions(app);
        end

        % Callback function
        function but_pauseValueChanged2(app, event)
            value = app.but_pause.Value;
            vlc = getappdata(app.hand_editing,'vlc');
            vlc.pause();
            time = vlc.Current.Position/1000000;%in seconds;
            func_time_change(app,time);
        end

        % Callback function
        function but_playValueChanged2(app, event)
            value = app.but_play.Value;
            func_play(app);
        end

        % Button pushed function: but_play
        function but_playPushed(app, event)
            func_play(app);
        end

        % Button pushed function: but_pause
        function but_pauseButtonPushed(app, event)
            vlc = getappdata(app.hand_editing,'vlc');
            vlc.pause();
            time = vlc.Current.Position/1000000;%in seconds;
            func_time_change(app,time);
        end

        % Value changed function: spin_speed
        function spin_speedValueChanged(app, event)
            value = app.spin_speed.Value;
            vlc = getappdata(app.hand_editing,'vlc');
            vlc.Rate = value;
        end

        % Button pushed function: but_add_markers
        function but_add_markersButtonPushed(app, event)
            func_add_marker_types(app);
        end

        % Button pushed function: but_browse_script
        function but_browse_scriptButtonPushed(app, event)
            % Prompt to browse path
            if isempty(app.txt_script_remove_events.Value)
                [file,location] = uigetfile(fullfile(ctfroot,'*.m'));
            else
                [file,location] = uigetfile(fullfile(app.txt_script_remove_events.Value,'*.m'));
            end
            %addpath(location);
            app.txt_script_remove_events.Value = fullfile(location,file);%update file path
        end

        % Callback function: table_markers
        function table_markersSelectionChanged(app, event)
            selection = app.table_markers.Selection;%[row,column]
            tab = app.table_markers;%table in gui
            tab_data = get(tab,'Data');
            lat = tab_data{selection(1),3};
            func_time_change(app,lat)
        end

        % Button pushed function: but_remove_selected
        function but_remove_selectedButtonPushed(app, event)
            selection = app.table_markers.Selection;%[row,column]
            func_undo_marker(app,selection(1));%remove the selected row
        end

        % Button pushed function: but_plot_EEG_filtered
        function but_plot_EEG_filteredButtonPushed(app, event)
            % Plot EEG at the current time in a pop up window
            func_plot_EEG(app,1);
        end

        % Button pushed function: but_plot_EEG_raw
        function but_plot_EEG_rawButtonPushed(app, event)
            func_plot_EEG(app,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.hand_editing = uifigure('Visible', 'off');
            app.hand_editing.Name = 'AVOCODO';
            app.hand_editing.CloseRequestFcn = createCallbackFcn(app, @hand_editingCloseRequest, true);
            app.axis_spectrogram = uiaxes(app.hand_editing);
            xlabel(app.axis_spectrogram, 'Time (second)')
            ylabel(app.axis_spectrogram, 'Frequency (Hz)')
            zlabel(app.axis_spectrogram, 'Z')
            app.DatafolderLabel = uilabel(app.hand_editing);
            app.DatafolderLabel.Text = 'Data folder';
            app.txt_path_data = uieditfield(app.hand_editing, 'text');
            app.but_path_data = uibutton(app.hand_editing, 'push');
            app.but_path_data.ButtonPushedFcn = createCallbackFcn(app, @but_path_dataButtonPushed, true);
            app.but_path_data.Text = 'Browse Data';
            app.but_load_eeg = uibutton(app.hand_editing, 'push');
            app.but_load_eeg.ButtonPushedFcn = createCallbackFcn(app, @but_load_eegButtonPushed, true);
            app.but_load_eeg.Text = 'Load EEG and videos';
            app.EEGloadedLampLabel = uilabel(app.hand_editing);
            app.EEGloadedLampLabel.Text = 'EEG loaded?';
            app.lamp_load_eeg = uilamp(app.hand_editing);
            app.VideosloadedLabel = uilabel(app.hand_editing);
            app.VideosloadedLabel.Text = 'Videos loaded?';
            app.lamp_load_video = uilamp(app.hand_editing);
            app.ChoosevideoLabel = uilabel(app.hand_editing);
            app.ChoosevideoLabel.Text = 'Choose video';
            app.drop_number_videos = uidropdown(app.hand_editing);
            app.drop_number_videos.Items = {'1'};
            app.drop_number_videos.ValueChangedFcn = createCallbackFcn(app, @drop_number_videosValueChanged, true);
            app.drop_number_videos.Value = '1';
            app.slide_video = uislider(app.hand_editing);
            app.slide_video.MajorTicks = [0 50 100];
            app.slide_video.ValueChangedFcn = createCallbackFcn(app, @slide_videoValueChanged, true);
            app.slide_video.ValueChangingFcn = createCallbackFcn(app, @slide_videoValueChanging, true);
            app.slide_video.MinorTicks = [];
            app.secondsLabel_2 = uilabel(app.hand_editing);
            app.secondsLabel_2.Text = 'seconds';
            app.number_slider = uieditfield(app.hand_editing, 'numeric');
            app.number_slider.ValueChangedFcn = createCallbackFcn(app, @number_sliderValueChanged, true);
            app.but_sync = uibutton(app.hand_editing, 'push');
            app.but_sync.ButtonPushedFcn = createCallbackFcn(app, @but_syncButtonPushed, true);
            app.but_sync.Text = 'Sync with video';
            app.FilesinpathListBoxLabel = uilabel(app.hand_editing);
            app.FilesinpathListBoxLabel.Text = {'Files'; 'in'; 'path'};
            app.list_eeg_files = uilistbox(app.hand_editing);
            app.list_eeg_files.Items = {'No files selected'};
            app.list_eeg_files.Value = 'No files selected';
            app.table_markers = uitable(app.hand_editing);
            app.table_markers.ColumnName = {'Type'; 'Latency in EEG'; 'Latency in video'; '# video'};
            app.table_markers.RowName = {};
            app.table_markers.SelectionChangedFcn = createCallbackFcn(app, @table_markersSelectionChanged, true);
            app.TableofaddedmarkersLabel = uilabel(app.hand_editing);
            app.TableofaddedmarkersLabel.Text = 'Table of added markers';
            app.but_drop = uibutton(app.hand_editing, 'push');
            app.but_drop.ButtonPushedFcn = createCallbackFcn(app, @but_dropButtonPushed, true);
            app.but_drop.Text = 'Drop';
            app.but_undo = uibutton(app.hand_editing, 'push');
            app.but_undo.ButtonPushedFcn = createCallbackFcn(app, @but_undoButtonPushed, true);
            app.but_undo.Text = 'Remove last';
            app.but_redo = uibutton(app.hand_editing, 'push');
            app.but_redo.ButtonPushedFcn = createCallbackFcn(app, @but_redoButtonPushed, true);
            app.but_redo.Text = 'Redo';
            app.EditFieldLabel = uilabel(app.hand_editing);
            app.EditFieldLabel.Text = 'An additional .m script to modify events based on specific rules';
            app.txt_script_remove_events = uieditfield(app.hand_editing, 'text');
            app.but_browse_script = uibutton(app.hand_editing, 'push');
            app.but_browse_script.ButtonPushedFcn = createCallbackFcn(app, @but_browse_scriptButtonPushed, true);
            app.but_browse_script.Text = 'Browse Script';
            app.but_export = uibutton(app.hand_editing, 'push');
            app.but_export.ButtonPushedFcn = createCallbackFcn(app, @but_exportButtonPushed, true);
            app.but_export.Text = 'Export!';
            app.but_export_additional = uibutton(app.hand_editing, 'push');
            app.but_export_additional.ButtonPushedFcn = createCallbackFcn(app, @but_export_additionalButtonPushed, true);
            app.but_export_additional.Text = {'Export'; 'with an additional'; 'script!'};
            app.WindowwidthLabel = uilabel(app.hand_editing);
            app.WindowwidthLabel.Text = 'Window width';
            app.number_window_width = uieditfield(app.hand_editing, 'numeric');
            app.number_window_width.Limits = [0 Inf];
            app.number_window_width.ValueChangedFcn = createCallbackFcn(app, @number_window_widthValueChanged, true);
            app.number_window_width.Value = 10;
            app.secondsLabel_3 = uilabel(app.hand_editing);
            app.secondsLabel_3.Text = 'seconds';
            app.but_back_more = uibutton(app.hand_editing, 'push');
            app.but_back_more.ButtonPushedFcn = createCallbackFcn(app, @but_back_moreButtonPushed, true);
            app.but_back_more.Text = '<<';
            app.but_back = uibutton(app.hand_editing, 'push');
            app.but_back.ButtonPushedFcn = createCallbackFcn(app, @but_backButtonPushed, true);
            app.but_back.Text = '<';
            app.but_forward = uibutton(app.hand_editing, 'push');
            app.but_forward.ButtonPushedFcn = createCallbackFcn(app, @but_forwardButtonPushed, true);
            app.but_forward.Text = '>';
            app.but_forward_more = uibutton(app.hand_editing, 'push');
            app.but_forward_more.ButtonPushedFcn = createCallbackFcn(app, @but_forward_moreButtonPushed, true);
            app.but_forward_more.Text = '>>';
            app.aLabel = uilabel(app.hand_editing);
            app.aLabel.Text = 'a';
            app.InstructionsSwitchLabel = uilabel(app.hand_editing);
            app.InstructionsSwitchLabel.Text = 'Instructions';
            app.switch_instructions = uiswitch(app.hand_editing, 'slider');
            app.switch_instructions.ValueChangedFcn = createCallbackFcn(app, @switch_instructionsValueChanged, true);
            app.txt_instruction_1 = uilabel(app.hand_editing);
            app.txt_instruction_1.Visible = 'off';
            app.txt_instruction_1.Text = '1. Specify path to data';
            app.txt_instruction_2 = uilabel(app.hand_editing);
            app.txt_instruction_2.Visible = 'off';
            app.txt_instruction_2.Text = '2. Select an EEG file';
            app.txt_instruction_3 = uilabel(app.hand_editing);
            app.txt_instruction_3.Visible = 'off';
            app.txt_instruction_3.Text = '3. Load EEG and videos';
            app.txt_instruction_4 = uilabel(app.hand_editing);
            app.txt_instruction_4.Visible = 'off';
            app.txt_instruction_4.Text = '4. Choose a video (if >1 files)';
            app.txt_instruction_5 = uilabel(app.hand_editing);
            app.txt_instruction_5.Visible = 'off';
            app.txt_instruction_5.Text = '5. Play video using the button below. (Don''t control playback on VLC.) Use slider and arrows to go forward/backward.';
            app.txt_instruction_6 = uilabel(app.hand_editing);
            app.txt_instruction_6.Visible = 'off';
            app.txt_instruction_6.Text = '6. Drop markers at time instances. Setup markers as needed.';
            app.txt_instruction_7 = uilabel(app.hand_editing);
            app.txt_instruction_7.Visible = 'off';
            app.txt_instruction_7.Text = '8. You can undo/redo if a marker is dropped at a wrong time.';
            app.txt_instruction_8 = uilabel(app.hand_editing);
            app.txt_instruction_8.Visible = 'off';
            app.txt_instruction_8.Text = '7. The markers you dropped are shown here.';
            app.txt_instruction_9 = uilabel(app.hand_editing);
            app.txt_instruction_9.Visible = 'off';
            app.txt_instruction_9.Text = '9. Export EEG once you are done!';
            app.txt_instruction_10 = uilabel(app.hand_editing);
            app.txt_instruction_10.Visible = 'off';
            app.txt_instruction_10.Text = '10. You can set up a rule to filter markers using script and then export.';
            app.but_play = uibutton(app.hand_editing, 'push');
            app.but_play.ButtonPushedFcn = createCallbackFcn(app, @but_playPushed, true);
            app.but_play.Text = 'Play';
            app.but_pause = uibutton(app.hand_editing, 'push');
            app.but_pause.ButtonPushedFcn = createCallbackFcn(app, @but_pauseButtonPushed, true);
            app.but_pause.Text = 'Pause';
            app.PlaybackspeedSpinnerLabel = uilabel(app.hand_editing);
            app.PlaybackspeedSpinnerLabel.Text = {'Playback'; 'speed'};
            app.spin_speed = uispinner(app.hand_editing);
            app.spin_speed.Step = 0.2;
            app.spin_speed.Limits = [0.6 2];
            app.spin_speed.ValueChangedFcn = createCallbackFcn(app, @spin_speedValueChanged, true);
            app.spin_speed.Value = 1;
            app.but_add_markers = uibutton(app.hand_editing, 'push');
            app.but_add_markers.ButtonPushedFcn = createCallbackFcn(app, @but_add_markersButtonPushed, true);
            app.but_add_markers.Text = 'Setup markers';
            app.txt_marker_type = uidropdown(app.hand_editing);
            app.txt_marker_type.Items = {};
            app.txt_marker_type.Value = {};
            app.markerLabel = uilabel(app.hand_editing);
            app.markerLabel.Text = 'marker';
            app.VideotimeLabel = uilabel(app.hand_editing);
            app.VideotimeLabel.Text = 'Video time:';
            app.txt_video_time = uilabel(app.hand_editing);
            app.txt_video_time.Text = '00:00';
            app.but_remove_selected = uibutton(app.hand_editing, 'push');
            app.but_remove_selected.ButtonPushedFcn = createCallbackFcn(app, @but_remove_selectedButtonPushed, true);
            app.but_remove_selected.Text = 'Remove selected';
            app.but_plot_EEG_filtered = uibutton(app.hand_editing, 'push');
            app.but_plot_EEG_filtered.ButtonPushedFcn = createCallbackFcn(app, @but_plot_EEG_filteredButtonPushed, true);
            app.but_plot_EEG_filtered.Text = 'Plot filtered EEG';
            app.but_plot_EEG_raw = uibutton(app.hand_editing, 'push');
            app.but_plot_EEG_raw.ButtonPushedFcn = createCallbackFcn(app, @but_plot_EEG_rawButtonPushed, true);
            app.but_plot_EEG_raw.Text = 'Plot Raw EEG';
            % Show the figure after all components are created
            app.hand_editing.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = run_AVOCODO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.hand_editing)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.hand_editing)
        end
    end
end